classdef gui0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProccesingSoundUIFigure  matlab.ui.Figure
        hearButton               matlab.ui.control.Button
        RecButton                matlab.ui.control.Button
        VolSliderLabel           matlab.ui.control.Label
        VolSlider                matlab.ui.control.Slider
        FilterDropDownLabel      matlab.ui.control.Label
        FilterDropDown           matlab.ui.control.DropDown
        PlayButton               matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        SelectFileButton         matlab.ui.control.Button
        FrecLabel                matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        AmpLabel                 matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        SwitchLabel              matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        ALabel                   matlab.ui.control.Label
        AEditField               matlab.ui.control.EditField
        BEditFieldLabel          matlab.ui.control.Label
        BEditField               matlab.ui.control.EditField
        Gauge                    matlab.ui.control.SemicircularGauge
        UIAxes                   matlab.ui.control.UIAxes
        Label_3                  matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
        PlayInvButton            matlab.ui.control.Button
        SumButton                matlab.ui.control.Button
        low_passCheckBox         matlab.ui.control.CheckBox
        band_passCheckBox        matlab.ui.control.CheckBox
        high_passCheckBox        matlab.ui.control.CheckBox
        Label_4                  matlab.ui.control.Label
        UIAxes2                  matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Property % Description
        s;
        p;
        m;
        f;
        v;
        a;
        b;
        pi;
        g;
        low;
        band;
        high
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.BackgroundColor = [0.15 0.15 0.15];
            app.UIAxes.XColor = [0.15 0.15 0.15];  
            app.UIAxes2.BackgroundColor = [0.15 0.15 0.15];
            app.UIAxes2.XColor = [0.15 0.15 0.15];              
        end

        % Button pushed function: hearButton
        function hearButtonPushed(app, event)
            app.s = 0;
            app.f = app.FilterDropDown.Value;
            app.v = 0.1;
            app.a= app.AEditField.Value;
            app.b = app.BEditField.Value;
            app.m = app.Switch.Value;            
            app.g = app.DropDown.Value;
            prueba2_audio_real
        end

        % Button pushed function: RecButton
        function RecButtonPushed(app, event)
            app.s = 0;
            app.p = 0;
            app.pi = 0;
            app.f = app.FilterDropDown.Value;
            app.v = 0.1;            
            app.a= app.AEditField.Value;
            app.b = app.BEditField.Value;
            app.m = app.Switch.Value;     
            app.g = app.DropDown.Value;           
            audio_recorder
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            app.s = 0;
            app.p = 0;
            app.pi = 0;
            app.f = app.FilterDropDown.Value;
            app.v = 0.1;            
            app.a= app.AEditField.Value;
            app.b = app.BEditField.Value;     
            app.m = app.Switch.Value;    
            app.g = app.DropDown.Value;            
            file_input          
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           app.s = 1;
           app.p = 0;
           app.pi = 0;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.p = 1;
            app.s = 0;
            app.pi = 0;
        end

        % Callback function
        function ModoSwitchValueChanged(app, event)
            %app.m = app.ModoSwitch.Value;
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.m = app.Switch.Value;
            
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            app.f = app.FilterDropDown.Value;
            
        end

        % Value changed function: VolSlider
        function VolSliderValueChanged(app, event)
            app.v = app.VolSlider.Value
            
        end

        % Callback function
        function AEditFieldValueChanged(app, event)

        end

        % Value changed function: AEditField
        function AEditFieldValueChanged2(app, event)
            app.a= app.AEditField.Value;
            
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            app.b = app.BEditField.Value;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.g = app.DropDown.Value;
            
        end

        % Button pushed function: PlayInvButton
        function PlayInvButtonPushed(app, event)
            app.s = 0;
            rev_audio;
        end

        % Button pushed function: SumButton
        function SumButtonPushed(app, event)
            app.s = 0;
            app.p = 0;
            app.pi = 0;
            app.f = app.FilterDropDown.Value;
            app.v = 0.1;            
            app.a= app.AEditField.Value;
            app.b = app.BEditField.Value;
            app.m = app.Switch.Value;     
            app.g = app.DropDown.Value;   
            sum_sound
        end

        % Value changed function: low_passCheckBox
        function low_passCheckBoxValueChanged(app, event)
            app.low = app.low_passCheckBox.Value;
            
        end

        % Value changed function: band_passCheckBox
        function band_passCheckBoxValueChanged(app, event)
            app.band = app.band_passCheckBox.Value;
            
        end

        % Value changed function: high_passCheckBox
        function high_passCheckBoxValueChanged(app, event)
            app.high = app.high_passCheckBox.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProccesingSoundUIFigure = uifigure;
            app.ProccesingSoundUIFigure.Name = 'Proccesing Sound';
            app.hearButton = uibutton(app.ProccesingSoundUIFigure, 'push');
            app.hearButton.ButtonPushedFcn = createCallbackFcn(app, @hearButtonPushed, true);
            app.hearButton.Text = 'hear';
            app.RecButton = uibutton(app.ProccesingSoundUIFigure, 'push');
            app.RecButton.ButtonPushedFcn = createCallbackFcn(app, @RecButtonPushed, true);
            app.RecButton.Text = 'Rec';
            app.VolSliderLabel = uilabel(app.ProccesingSoundUIFigure);
            app.VolSliderLabel.Text = 'Vol';
            app.VolSlider = uislider(app.ProccesingSoundUIFigure);
            app.VolSlider.Limits = [0 1];
            app.VolSlider.MajorTicks = [];
            app.VolSlider.MajorTickLabels = {};
            app.VolSlider.ValueChangedFcn = createCallbackFcn(app, @VolSliderValueChanged, true);
            app.VolSlider.MinorTicks = [];
            app.VolSlider.Value = 0.1;
            app.FilterDropDownLabel = uilabel(app.ProccesingSoundUIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.ProccesingSoundUIFigure);
            app.FilterDropDown.Items = {'Eq. Dif', 'Res. Frec', 'No filter'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'No filter';
            app.PlayButton = uibutton(app.ProccesingSoundUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.ProccesingSoundUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SelectFileButton = uibutton(app.ProccesingSoundUIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.FrecLabel = uilabel(app.ProccesingSoundUIFigure);
            app.FrecLabel.Text = 'Frec:';
            app.Label = uilabel(app.ProccesingSoundUIFigure);
            app.Label.Text = '';
            app.AmpLabel = uilabel(app.ProccesingSoundUIFigure);
            app.AmpLabel.Text = 'Amp:';
            app.Label_2 = uilabel(app.ProccesingSoundUIFigure);
            app.Label_2.Text = '';
            app.SwitchLabel = uilabel(app.ProccesingSoundUIFigure);
            app.SwitchLabel.Text = '';
            app.Switch = uiswitch(app.ProccesingSoundUIFigure, 'slider');
            app.Switch.Items = {'Estereo', 'Mono'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Estereo';
            app.ALabel = uilabel(app.ProccesingSoundUIFigure);
            app.ALabel.Text = 'A:';
            app.AEditField = uieditfield(app.ProccesingSoundUIFigure, 'text');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged2, true);
            app.AEditField.Value = '[1	-1.9425	2.1192	-1.2167	0.4128]';
            app.BEditFieldLabel = uilabel(app.ProccesingSoundUIFigure);
            app.BEditFieldLabel.Text = 'B:';
            app.BEditField = uieditfield(app.ProccesingSoundUIFigure, 'text');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.BEditField.Value = '[0.0675	0 -0.1349 0	0.0675]';
            app.Gauge = uigauge(app.ProccesingSoundUIFigure, 'semicircular');
            app.Gauge.Limits = [0 0.2];
            app.UIAxes = uiaxes(app.ProccesingSoundUIFigure);
            app.Label_3 = uilabel(app.ProccesingSoundUIFigure);
            app.Label_3.Text = '';
            app.DropDown = uidropdown(app.ProccesingSoundUIFigure);
            app.DropDown.Items = {'No Graphic', 'Magnitude', 'Phase'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'No Graphic';
            app.PlayInvButton = uibutton(app.ProccesingSoundUIFigure, 'push');
            app.PlayInvButton.ButtonPushedFcn = createCallbackFcn(app, @PlayInvButtonPushed, true);
            app.PlayInvButton.Text = 'Play Inv';
            app.SumButton = uibutton(app.ProccesingSoundUIFigure, 'push');
            app.SumButton.ButtonPushedFcn = createCallbackFcn(app, @SumButtonPushed, true);
            app.SumButton.Text = 'Sum';
            app.low_passCheckBox = uicheckbox(app.ProccesingSoundUIFigure);
            app.low_passCheckBox.ValueChangedFcn = createCallbackFcn(app, @low_passCheckBoxValueChanged, true);
            app.low_passCheckBox.Text = 'low_pass';
            app.band_passCheckBox = uicheckbox(app.ProccesingSoundUIFigure);
            app.band_passCheckBox.ValueChangedFcn = createCallbackFcn(app, @band_passCheckBoxValueChanged, true);
            app.band_passCheckBox.Text = 'band_pass';
            app.high_passCheckBox = uicheckbox(app.ProccesingSoundUIFigure);
            app.high_passCheckBox.ValueChangedFcn = createCallbackFcn(app, @high_passCheckBoxValueChanged, true);
            app.high_passCheckBox.Text = 'high_pass';
            app.Label_4 = uilabel(app.ProccesingSoundUIFigure);
            app.Label_4.Text = '';
            app.UIAxes2 = uiaxes(app.ProccesingSoundUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui0

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProccesingSoundUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProccesingSoundUIFigure)
        end
    end
end